classdef Esinam7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        EXAMINEButton  matlab.ui.control.Button
        Image          matlab.ui.control.Image
        Image2         matlab.ui.control.Image
        Image3         matlab.ui.control.Image
        Image4         matlab.ui.control.Image
        UITable        matlab.ui.control.Table
        Label          matlab.ui.control.Label
        Label2         matlab.ui.control.Label
        Label3         matlab.ui.control.Label
        Label4         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EXAMINEButton
        function EXAMINEButtonPushed(app, event)
try
    % --- Step 1: Load Original Image ---
    [file, path] = uigetfile({'*.jpg;*.png;*.jpeg;*.bmp'}, 'Select Image');
    if isequal(file,0)
        uialert(app.UIFigure, 'No image selected!', 'Error');
        return;
    end
    imgPath = fullfile(path,file);
    I = imread(imgPath);

    % Convert to grayscale if needed
    if size(I,3)==3
        Igray = rgb2gray(I);
    else
        Igray = I;
    end

    % --- Step 2: Contrast Enhancement ---
    Ienh = adapthisteq(Igray);      % double [0,1]
    Ienh_uint8 = im2uint8(Ienh);    % convert to uint8

    % --- Step 3: Thresholding ---
    level = graythresh(Ienh);
    BW = imbinarize(Ienh, level);
    BW = imfill(BW,'holes');
    BW = bwareaopen(BW, 50);

    % --- Step 4: Segmentation ---
    [L, num] = bwlabel(BW);

    % Initialize annotated image as RGB
    annotatedImg = repmat(Ienh_uint8, [1, 1, 3]);

    if num > 0
        stats = regionprops(L, Ienh_uint8, ...
            'Area', 'Perimeter', 'Centroid', 'Eccentricity', ...
            'MeanIntensity', 'BoundingBox');

        for k = 1:length(stats)
            bbox = round(stats(k).BoundingBox);
            x1 = max(1, bbox(1));
            y1 = max(1, bbox(2));
            x2 = min(size(annotatedImg, 2), bbox(1) + bbox(3) - 1);
            y2 = min(size(annotatedImg, 1), bbox(2) + bbox(4) - 1);

            % Red bounding box
            borderWidth = 2;
            for bw = 0:borderWidth-1
                annotatedImg(y1+bw, x1:x2, 1) = 255; % Top
                annotatedImg(y1+bw, x1:x2, 2:3) = 0;
                annotatedImg(y2-bw, x1:x2, 1) = 255; % Bottom
                annotatedImg(y2-bw, x1:x2, 2:3) = 0;
                annotatedImg(y1:y2, x1+bw, 1) = 255; % Left
                annotatedImg(y1:y2, x1+bw, 2:3) = 0;
                annotatedImg(y1:y2, x2-bw, 1) = 255; % Right
                annotatedImg(y1:y2, x2-bw, 2:3) = 0;
            end

            % Green centroid
            centroid = round(stats(k).Centroid);
            cx = centroid(1); cy = centroid(2);
            centroidSize = 2;
            for i = -centroidSize:centroidSize
                for j = -centroidSize:centroidSize
                    if cy+i > 0 && cy+i <= size(annotatedImg,1) && ...
                       cx+j > 0 && cx+j <= size(annotatedImg,2)
                        annotatedImg(cy+i, cx+j, 2) = 255; % Green
                        annotatedImg(cy+i, cx+j, [1 3]) = 0;
                    end
                end
            end
        end
    else
        stats = [];
    end

    % --- Step 5: Display in Image components (convert all to RGB) ---
    app.Image.ImageSource  = cat(3, Igray, Igray, Igray);                % Original
    app.Image2.ImageSource = cat(3, Ienh_uint8, Ienh_uint8, Ienh_uint8); % Contrast Enhanced
    app.Image3.ImageSource = cat(3, uint8(BW)*255, uint8(BW)*255, uint8(BW)*255); % Thresholded
    app.Image4.ImageSource = uint8(annotatedImg);                        % Segmented / Annotated

    % --- Step 6: Update Labels (make sure you created them in App Designer) ---
    app.Label.Text = 'Original Image';
    app.Label2.Text = 'Contrast Enhancement';
    app.Label3.Text = 'Thresholding';
    app.Label4.Text = 'Segmentation / Annotated';

    % --- Step 7: Display Features in UITable ---
    if ~isempty(stats)
        featureTable = table((1:length(stats))', ...
            [stats.Area]', [stats.Perimeter]', [stats.Eccentricity]', [stats.MeanIntensity]', ...
            'VariableNames', {'RegionID','Area','Perimeter','Eccentricity','MeanIntensity'});

        app.UITable.Data = table2cell(featureTable);
        app.UITable.ColumnName = featureTable.Properties.VariableNames;
    else
        app.UITable.Data = {};
        app.UITable.ColumnName = {};
        uialert(app.UIFigure, 'No regions detected in the image', 'Warning');
    end

catch ME
    uialert(app.UIFigure, ME.message, 'Error');
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EXAMINEButton = uibutton(app.UIFigure, 'push');
            app.EXAMINEButton.ButtonPushedFcn = createCallbackFcn(app, @EXAMINEButtonPushed, true);
            app.EXAMINEButton.Text = 'EXAMINE';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            app.Image4 = uiimage(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'};
            app.UITable.RowName = {};
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Label4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Esinam7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end